classdef Bode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DiagramadeBodePanel   matlab.ui.container.Panel
        SistemaListBox        matlab.ui.control.ListBox
        SistemaListBoxLabel   matlab.ui.control.Label
        FrequnciaSlider       matlab.ui.control.Slider
        FrequnciaSliderLabel  matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        UIAxes_phase          matlab.ui.control.UIAxes
        UIAxes_mag            matlab.ui.control.UIAxes
        UIAxes_in             matlab.ui.control.UIAxes
        UIAxes_out            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        H                       % Variavel para armazenar a funcao de transferencia selecionada
        w                       % vetor de frequencia para o plot do diagrama de bode
        Input_handle            % handle para o plot do sinal de entrada
        Output_handle           % handle para o plot do sinal de saida
        Magnitude_handle        % handle para o plot da magnitude no diagrama de bode
        Phase_handle            % handle para o plot da fase no diagrama de bode
        MagnitudeXline          % handle para a linha vertical no grafico da magnitude no diagrama de bode
        PhaseXline              % handle para a linha vertical no grafico da fase no diagrama de bode
    end
    
    
    methods (Access = private)
        
        function UpdatePlots(app, w0)
            %% --- Bode Diagram ---
            [mag, phase, wout] = bode(app.H, app.w);
            mag = squeeze(mag);
            phase = squeeze(phase);
            app.Magnitude_handle.XData = wout;
            app.Magnitude_handle.YData = 20*log10(mag);
            app.MagnitudeXline.Value = w0;

            app.Phase_handle.XData = wout;
            app.Phase_handle.YData = phase;
            app.PhaseXline.Value = w0;


            %% --- Input Signal Plot---
            t_max = 1000*6*pi/w0;       %max timestep in ms
            t = linspace(0,t_max);
            y = sin(w0*t/1000);
            app.Input_handle.YData = y;
            app.Input_handle.XData = t;
            app.UIAxes_in.XTick = linspace(0, t_max, 4);
            app.UIAxes_in.XLim = [0 t_max];

            %% --- Output Signal Plot ---
            H_w = freqresp(app.H, w0);
            A = abs(H_w);
            phi = angle(H_w);
            u = A*sin(w0*t/1000 + phi);
            app.Output_handle.YData = u;
            app.Output_handle.XData = t;
            app.UIAxes_out.XTick = linspace(0, t_max, 4);
            app.UIAxes_out.XLim = [0 t_max];

        end
        
        function MakePlots(app)
            %% --- Bode Diagram ---
            w0 = 10^app.FrequnciaSlider.Value;
            [mag, phase, wout] = bode(app.H, app.w);
            mag = squeeze(mag);
            phase = squeeze(phase);

            % --- Plot of the Magnitude ---
            cla(app.UIAxes_mag);
            app.Magnitude_handle = semilogx(app.UIAxes_mag, wout, 20*log10(mag), 'b', 'LineWidth', 1.5);
            hold(app.UIAxes_mag, 'on');
            app.MagnitudeXline = xline(app.UIAxes_mag, w0, 'r--', 'LineWidth', 1.5);
            hold(app.UIAxes_mag, 'off');
            grid(app.UIAxes_mag, 'on');
            xlabel(app.UIAxes_mag, 'Frequência (rad/s)');
            ylabel(app.UIAxes_mag, 'Amplitude - |G(jω)|');
            app.UIAxes_mag.XLimMode = 'auto';
            % --- Plot of the Phase ---
            cla(app.UIAxes_phase);
            app.Phase_handle = semilogx(app.UIAxes_phase, wout, phase, 'b', 'LineWidth', 1.5);
            hold(app.UIAxes_phase, 'on');
            app.PhaseXline = xline(app.UIAxes_phase, w0, 'r--', 'LineWidth', 1.5);
            hold(app.UIAxes_phase, 'off');
            grid(app.UIAxes_phase, 'on');
            xlabel(app.UIAxes_phase, 'Frequência (rad/s)');
            ylabel(app.UIAxes_phase, 'Fase - ∠G(jω) (º)');
            app.UIAxes_phase.XLimMode = 'auto';
            %% --- Input Signal Plot---
            t_max = 1000*6*pi/w0;       %max timestep in ms
            t = linspace(0,t_max);
            y = sin(w0*t/1000);
            cla(app.UIAxes_in);
            app.Input_handle = plot(app.UIAxes_in, t, y, 'LineWidth', 1.5);
            xticks(app.UIAxes_in, linspace(0, t_max, 4));
            xtickformat(app.UIAxes_in, '%.0f');
            xlim(app.UIAxes_in, [0, t_max]);
            ylim(app.UIAxes_in, [-1, 1]);
            xlabel(app.UIAxes_in, 'Tempo (ms)');
            ylabel(app.UIAxes_in, 'Amplitude');
            title(app.UIAxes_in, 'Entrada');
            app.UIAxes_in.XLimMode = 'auto';
            app.UIAxes_in.XLim = [0 t_max];


            %% --- Output Signal Plot ---
            H_w = freqresp(app.H, w0);
            A = abs(H_w);
            phi = angle(H_w);
            u = A*sin(w0*t/1000 + phi);
            cla(app.UIAxes_out);
            app.Output_handle = plot(app.UIAxes_out, t, u, 'LineWidth', 1.5); % in ms
            xticks(app.UIAxes_out, linspace(0, t_max, 4));
            xtickformat(app.UIAxes_out, '%.0f');
            xlim(app.UIAxes_out, [0, t_max]);
            ylim(app.UIAxes_out, [-1, 1]);
            xlabel(app.UIAxes_out, 'Tempo (ms)');
            ylabel(app.UIAxes_out, 'Amplitude');
            title(app.UIAxes_out, 'Saida');
            app.UIAxes_out.XLimMode = 'auto';
            app.UIAxes_out.XLim = [0 t_max];

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% --- Bode Diagram Plot ---
            % --- create the frequency vector used in multiple callbacks ---
            w0 = 10^app.FrequnciaSlider.Value;
            wi = 10^app.FrequnciaSlider.Limits(1);
            wf = 10^app.FrequnciaSlider.Limits(2);
            app.w = logspace(log10(wi), log10(wf), 500);    
            wp = sqrt(wi*wf);
            
            % --- Initial Transfer function ---
            app.H = tf([1],[1/wp 1]);       %starts as a sinusoidal signal by default

            app.MakePlots();

        end

        % Value changed function: FrequnciaSlider
        function FrequnciaSliderValueChanged(app, event)
            w0 = 10^app.FrequnciaSlider.Value;
            app.UpdatePlots(w0);
        end

        % Value changed function: SistemaListBox
        function SistemaListBoxValueChanged(app, event)
            clickedItem = app.SistemaListBox.Value;   % the label of the item the user clicked
    
            wi = 10^app.FrequnciaSlider.Limits(1);
            wf = 10^app.FrequnciaSlider.Limits(2);  
            
            w0 = 10^app.FrequnciaSlider.Value;

            switch clickedItem
                case "Filtro Passa Baixa"
                    wp = sqrt(wi*wf);
                    app.H = tf([1],[1/wp 1]);
                    app.UpdatePlots(w0);
                case "Filtro Passa Banda"
                    wp = sqrt(wi*wf);   % center frequency
                    Q = 5;     % quality factor
                    app.H = tf([0 wp/Q 0], [1 wp/Q wp^2]);
                    app.UpdatePlots(w0);
                case "Filtro Passa Alta"
                    wc = sqrt(wi*wf);
                    app.H = tf([1 0],[1 wc]);
                    app.UpdatePlots(w0);
                case "Ressoador de 2ª ordem"
                    w_0 = sqrt(wi*wf);
                    wa = w_0/2;     % antiresonance frequency (rad/s) one octave below w0
                    wr = 2*w_0;    % resonance frequency (rad/s) one octave above w0
                    zeta = 0.1; % damping ratio of resonance (small for sharp peak)
                    app.H = tf([1 0 wa^2], [1 2*zeta*wr wr^2]);
                    app.UpdatePlots(w0);
            end
            app.MagnitudeXline.Value = w0;
            app.PhaseXline.Value = w0;
        end

        % Value changing function: FrequnciaSlider
        function FrequnciaSliderValueChanging(app, event)
            w0 = 10^event.Value;
            app.UpdatePlots(w0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiagramadeBodePanel = uipanel(app.UIFigure);
            app.DiagramadeBodePanel.Title = 'Diagrama de Bode';
            app.UIAxes_out = uiaxes(app.DiagramadeBodePanel);
            title(app.UIAxes_out, 'Saida')
            xlabel(app.UIAxes_out, 'Tempo (ms)')
            ylabel(app.UIAxes_out, 'y(t)')
            app.UIAxes_in = uiaxes(app.DiagramadeBodePanel);
            title(app.UIAxes_in, 'Entrada')
            xlabel(app.UIAxes_in, 'Tempo (ms)')
            ylabel(app.UIAxes_in, 'u(t)')
            app.UIAxes_mag = uiaxes(app.DiagramadeBodePanel);
            title(app.UIAxes_mag, 'Title')
            ylabel(app.UIAxes_mag, 'Y')
            zlabel(app.UIAxes_mag, 'Z')
            app.UIAxes_phase = uiaxes(app.DiagramadeBodePanel);
            title(app.UIAxes_phase, 'Title')
            xlabel(app.UIAxes_phase, 'X')
            ylabel(app.UIAxes_phase, 'Y')
            zlabel(app.UIAxes_phase, 'Z')
            app.Image = uiimage(app.DiagramadeBodePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Bode', 'Bode.png');
            app.FrequnciaSliderLabel = uilabel(app.DiagramadeBodePanel);
            app.FrequnciaSliderLabel.Text = 'Frequência';
            app.FrequnciaSlider = uislider(app.DiagramadeBodePanel);
            app.FrequnciaSlider.Limits = [1.3 3.3];
            app.FrequnciaSlider.MajorTicks = [1.3 1.7 2.1 2.5 2.9 3.3];
            app.FrequnciaSlider.MajorTickLabels = {'20', '50', '126', '316', '794', '2000'};
            app.FrequnciaSlider.ValueChangedFcn = createCallbackFcn(app, @FrequnciaSliderValueChanged, true);
            app.FrequnciaSlider.ValueChangingFcn = createCallbackFcn(app, @FrequnciaSliderValueChanging, true);
            app.FrequnciaSlider.Value = 1.3;
            app.SistemaListBoxLabel = uilabel(app.DiagramadeBodePanel);
            app.SistemaListBoxLabel.Text = 'Sistema';
            app.SistemaListBox = uilistbox(app.DiagramadeBodePanel);
            app.SistemaListBox.Items = {'Filtro Passa Baixa', 'Filtro Passa Banda', 'Filtro Passa Alta', 'Ressoador de 2ª ordem'};
            app.SistemaListBox.ValueChangedFcn = createCallbackFcn(app, @SistemaListBoxValueChanged, true);
            app.SistemaListBox.Value = 'Filtro Passa Baixa';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end