classdef PID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        IniciarSimulaaoButton  matlab.ui.control.StateButton
        PSlider                matlab.ui.control.Slider
        ISlider                matlab.ui.control.Slider
        DSlider                matlab.ui.control.Slider
        DSliderLabel           matlab.ui.control.Label
        ISliderLabel           matlab.ui.control.Label
        PSliderLabel           matlab.ui.control.Label
        PendulumAnimation      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Kp % Ganho proporcional
        Ki % Ganho Integrativo
        Kd % Ganho derivativo
        Ts % Periodo de amostragem
    end
    
    methods (Access = private)
        
        function pendulo = plotPendulo(app, x_vet, ax)                    %funcao interna para plotar o pendulo no UIaxis especificado nos argumentos
        
            l = 20;
            x = x_vet(1);
            theta = -x_vet(3);
            
            cla(ax);              % Clear axes, NOT figure
            hold(ax, 'on');
            
            pendulo.rect1 = rectangle(ax, 'Position', [-6+x 0 2 2], ...
                'Curvature', [1 1], 'FaceColor', [0 0 0]);
            
            pendulo.rect2 = rectangle(ax, 'Position', [4+x 0 2 2], ...
                'Curvature', [1 1], 'FaceColor', [0 0 0]);
            
            pendulo.rect3 = rectangle(ax, 'Position', [-6+x 2 12 5], ...
                'FaceColor', [0.1 0.5 1]);
            
            pendulo.l1 = line(ax, ...
                [x x+l*sin(theta)], ...
                [7 7+l*cos(theta)], ...
                'LineWidth', 5, 'Color', [1 0 0]);
            
            r = 1;
            xc = x + l*sin(theta);
            yc = 7 + l*cos(theta);
            
            pendulo.ball = rectangle(ax, ...
                'Position', [xc-r yc-r 2*r 2*r], ...
                'Curvature', [1 1], 'FaceColor', [1 0 0]);
            
            axis(ax, 'equal');
            axis(ax, [-50 50 -1 30]);
            
            drawnow;
            hold(ax, 'off');
        
        end

        function updatependulum(app, x_vet,pendulo)                        % funcao para atualizar a posicao das figuras que compoem o pendulo
            l=20;
            x=x_vet(1);
            theta=-x_vet(3);
            pendulo.rect1.Position=[-6+x 0 2 2];
            pendulo.rect2.Position=[4+x 0 2 2];
            pendulo.rect3.Position=[-6+x 2 12 5];
            r=1;
            xc=x+l*sin(theta);
            yc=7+l*cos(theta);
            d = r*2;
            px = xc-r;
            py = yc-r;
            pendulo.l1.XData=[x xc];
            pendulo.l1.YData=[7 yc];
            pendulo.ball.Position=[px py d d];
            drawnow
        end
        function x=pendulo_model(app, x,u,dt,M,m,l)                            % funcao para atualizar o estado do pendulo
            %simula modelo não-linear do pendulo invertido (atividade 4 e 5)
            g=9.81;
            %%
            x_d=x(2);
            th=x(3);
            th_d=x(4);
            
            F=u;
            x_dd =(-l*m*sin(th)*th_d^2 + F + g*m*cos(th)*sin(th))/(- m*cos(th)^2 + M + m);
            th_dd=(-l*m*cos(th)*sin(th)*th_d^2 + F*cos(th) + g*m*sin(th) + M*g*sin(th))/(l*(- m*cos(th)^2 + M + m));
            f=[x_d; x_dd; th_d; th_dd];
            x=x+f*dt;
            x(3)=wrapToPi(x(3));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Kp = app.PSlider.Limits(1);
            app.Ki = app.ISlider.Limits(1);
            app.Kd = app.DSlider.Limits(1);
      
        end

        % Value changed function: PSlider
        function PSliderValueChanged(app, event)
            app.Kp = app.PSlider.Value;
        end

        % Value changed function: IniciarSimulaaoButton
        function IniciarSimulaaoButtonValueChanged(app, event)
            M=1;
            m=0.1;
            l=0.4;
            g=9.81;
            
            %%
            Ts=0.1; %tempo de amostragem
            T=10; %tempo de simulacao
            dt=0.0001;
            t=0:dt:T;
            Nc=numel(t);
            td=0:Ts:T;
            Nd=numel(td);
            %%
            x=zeros(4,Nc);
            u=zeros(Nd,1);
            %%
            Nr=Ts/dt;
            kd=1;
            %% Parametros PID
            Kp=app.Kp;
            Kd=app.Kd;
            Ki=app.Ki;
            
            Ti=Kp/Ki;
            Td=Kd/Kp;
            %%
            erro_anterior=0;
            ui=0;
            H=[1 0 0 0;...
               0 0 1 0];
            %% condicao inicial
            x(:,1)=[0 0 deg2rad(10) 0]';
            pendulo=app.plotPendulo(x(:,1), app.PendulumAnimation);
            for k=1:Nc-1
                %% simula modelo nao-linear pendulo em tempo continuo
                x(:,k+1)=app.pendulo_model(x(:,k),u(kd),dt,M,m,l);
                if (mod(k,Nr)==0 || k==1) && kd<=numel(td)
                    %% simula controlador em tempo discreto
                    if k~=1
                        kd=kd+1;
                    end
                    y=H*x(:,k);
                    px=y(1); %posicao na direcao x
                    theta=y(2); %posicao angular
                    %% calcula sinal de erro
                    erro=0-theta; %erro de posicao angular
                    %% Controle PID
                    up=Kp*erro; %proporcional
                    ud=Kp*Td/Ts*(erro-erro_anterior); %derivativo (euler-backward)
                    ui=ui+Kp*Ts/Ti*erro; %integrativo (euler-backward)
                    u(kd)=up+ud+ui;
                    erro_anterior=erro;
            
                    %% atualiza frame da animacao 
                    app.updatependulum(x(:,k),pendulo);
                    pause(0.05)
                end
            end
        end

        % Value changed function: ISlider
        function ISliderValueChanged(app, event)
            app.Ki = app.ISlider.Value;
        end

        % Callback function: DSlider, PSlider
        function DSliderValueChanged(app, event)
            app.Kd = app.DSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PendulumAnimation = uiaxes(app.UIFigure);
            title(app.PendulumAnimation, 'Sistema Pendulo invertido no Carrinho')
            app.PendulumAnimation.XTick = [];
            app.PendulumAnimation.YTick = [];
            app.PSliderLabel = uilabel(app.UIFigure);
            app.PSliderLabel.Text = 'P';
            app.ISliderLabel = uilabel(app.UIFigure);
            app.ISliderLabel.Text = 'I';
            app.DSliderLabel = uilabel(app.UIFigure);
            app.DSliderLabel.Text = 'D';
            app.DSlider = uislider(app.UIFigure);
            app.DSlider.Limits = [0.1 5];
            app.DSlider.ValueChangedFcn = createCallbackFcn(app, @DSliderValueChanged, true);
            app.DSlider.Value = 0.1;
            app.ISlider = uislider(app.UIFigure);
            app.ISlider.Limits = [0.1 3];
            app.ISlider.ValueChangedFcn = createCallbackFcn(app, @ISliderValueChanged, true);
            app.ISlider.Value = 0.1;
            app.PSlider = uislider(app.UIFigure);
            app.PSlider.Limits = [0.1 20];
            app.PSlider.ValueChangedFcn = createCallbackFcn(app, @PSliderValueChanged, true);
            app.PSlider.ValueChangingFcn = createCallbackFcn(app, @DSliderValueChanged, true);
            app.PSlider.Value = 0.1;
            app.IniciarSimulaaoButton = uibutton(app.UIFigure, 'state');
            app.IniciarSimulaaoButton.ValueChangedFcn = createCallbackFcn(app, @IniciarSimulaaoButtonValueChanged, true);
            app.IniciarSimulaaoButton.Text = 'Iniciar Simulaçao';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end