classdef zplanedemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MapeamentoplanosplanozPanel  matlab.ui.container.Panel
        FrequnciadeAmostragemSlider  matlab.ui.control.Slider
        FrequnciadeAmostragemSliderLabel  matlab.ui.control.Label
        MtodoListBox                 matlab.ui.control.ListBox
        MtodoListBoxLabel            matlab.ui.control.Label
        ZPlaneAxes                   matlab.ui.control.UIAxes
        SPlaneAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         % Handle for updating the Z-plane data

    end
    
    properties (Access = private)
        Drawing logical = false                             % Flag para indicar que a açao de escrever esta em andamento 
        SDrawX double = []                                  % Buffer dos pontos a serem desenhados no eixo x do plano s
        SDrawY double = []                                  % Buffer dos pontos a serem desenhados no eixo y do plano s
        ZDrawX double = []                                  % Idem, para o plano z
        ZDrawY double = []                                  % Idem, para o plano z
        SPlaneHandle matlab.graphics.chart.primitive.Line   % Handle para o objeto do plot do plano s
        ZPlaneHandle matlab.graphics.chart.primitive.Line   % Handle para o objeto do plot do plano z
        transformation                                      % objeto que armazena a funçao de transformacao do plano s para o z
        Ta                                                  % periodo de amostragem
        SImagHandle matlab.graphics.chart.primitive.Line    %
        ZImagHandle matlab.graphics.chart.primitive.Line

    end

    methods (Access = private)
        function startDraw(app, src, event)
            % Only start drawing if click is inside SPlaneAxes
            cp = app.SPlaneAxes.CurrentPoint;
            x = cp(1,1); 
            y = cp(1,2);
            
            ax = app.SPlaneAxes;
            if x < ax.XLim(1) || x > ax.XLim(2) || y < ax.YLim(1) || y > ax.YLim(2)
                return  % click outside the axes: do nothing
            end
        
            app.Drawing = true;
            app.SDrawX = x;
            app.SDrawY = y;
        
            hold(app.SPlaneAxes, 'on');
            app.SPlaneHandle = plot(app.SPlaneAxes, app.SDrawX, app.SDrawY, 'r', 'LineWidth', 2, 'Tag', 'DrawnLine');
            s = x + 1i*y; 
            T = app.Ta; 
            z = app.transformation(s,T);

            app.ZDrawX = real(z);
            app.ZDrawY = imag(z);
            hold(app.ZPlaneAxes, 'on');
            app.ZPlaneHandle = plot(app.ZPlaneAxes, app.ZDrawX, app.ZDrawY, 'r', 'LineWidth', 2, 'Tag', 'DrawnLine');
        end

        function continueDraw(app, src, event)
            if ~app.Drawing
                return
            end
        
            cp = app.SPlaneAxes.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);
        
            app.SDrawX(end+1) = x;
            app.SDrawY(end+1) = y;
        
            app.SPlaneHandle.XData = app.SDrawX;
            app.SPlaneHandle.YData = app.SDrawY;
            
            s = x + 1i*y; 
            T = app.Ta; 
            z = app.transformation(s,T);
            app.ZDrawX(end+1) = real(z);
            app.ZDrawY(end+1) = imag(z);            

            app.ZPlaneHandle.XData = app.ZDrawX;
            app.ZPlaneHandle.YData = app.ZDrawY;
            
        end

        function stopDraw(app, src, event)
            app.Drawing = false;
        end

        function plotImagAxis(app)
            % Remove old curves first
            if ~isempty(app.SImagHandle) && all(isvalid(app.SImagHandle))
                delete(app.SImagHandle);
            end
            if ~isempty(app.ZImagHandle) && all(isvalid(app.ZImagHandle))
                delete(app.ZImagHandle);
            end
        
            % Plot the imaginary axis in S-plane
            hold(app.SPlaneAxes, 'on');
            app.SImagHandle = plot(app.SPlaneAxes, zeros(2), [-100 100], ...
                'b', 'LineWidth', 2, 'Tag', 'ImagAxis');
        
            % Plot the corresponding z-plane mapping shape
            % Use exact curves depending on the method
            method = app.MtodoListBox.Value;
            T = app.Ta;
            switch method
                case 'Exato'
                    % Exact mapping: unit circle
                    theta = linspace(-pi, pi, 360);
                    zReal = cos(theta);
                    zImag = sin(theta);
        
                case 'Euler-Forward'
                    % z = 1 + jωT (vertical line through 1)
                    omega_line = linspace(-100, 100, 2);
                    zReal = ones(size(omega_line))*1;
                    zImag = omega_line;
        
                case 'Euler-Backward'
                    % z = 1/(1 - jωT)
                    theta = linspace(-pi, pi, 360);
                    zReal = 1/2+ cos(theta)/2;
                    zImag = sin(theta)/2;
        
                case 'Tustin'
                    % z = (1 + (jωT/2))/(1 - (jωT/2)) → unit circle again
                    theta = linspace(-pi, pi, 360);
                    zReal = cos(theta);
                    zImag = sin(theta);
            end
        
            hold(app.ZPlaneAxes, 'on');
            app.ZImagHandle = plot(app.ZPlaneAxes, zReal, zImag, 'b', 'LineWidth', 2, ...
                'Tag', 'ImagAxis');
        
            % Keep axes limits
            xlim(app.ZPlaneAxes, [-2 2]);
            ylim(app.ZPlaneAxes, [-2 2]);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        % Get click location relative to the s-plane axes

            % Add point to s-plane
            xlim(app.SPlaneAxes, [-100, 100]);
            ylim(app.SPlaneAxes, [-100, 100]);
            app.SPlaneHandle = plot(app.SPlaneAxes, [], []);
            grid(app.SPlaneAxes, "on");
            app.transformation = @(s,T) exp(T*s);
            xlim(app.ZPlaneAxes,[-2, 2]);
            ylim(app.ZPlaneAxes,[-2, 2]);
            app.ZPlaneHandle = plot(app.ZPlaneAxes, [], []);
            zgrid(app.ZPlaneAxes);
            app.UIFigure.WindowButtonDownFcn  = @app.startDraw;
            app.UIFigure.WindowButtonMotionFcn = @app.continueDraw;
            app.UIFigure.WindowButtonUpFcn    = @app.stopDraw;

            %app.FrequnciadeAmostragemSlider.Value = app.FrequnciadeAmostragemSlider.Limits(2);
            app.Ta = 1/app.FrequnciadeAmostragemSlider.Value;
            plotImagAxis(app);
        end

        % Value changed function: MtodoListBox
        function MtodoListBoxValueChanged(app, event)
            clickedItem = app.MtodoListBox.Value;   % the label of the item the user clicked
    
            %disp(clickedItem)
            %set transformation
            switch clickedItem
                case "Exato"
                    app.transformation = @(s,T) exp(T*s);
                case "Euler-Backward"
                    app.transformation = @(s,T) 1/(1-T*s);
                case "Euler-Forward"
                    app.transformation = @(s,T) 1+T*s;
                case "Tustin"
                    app.transformation = @(s,T) (2+T*s)/(2-T*s);
            end
            
            %clear plots
            delete(findall(app.SPlaneAxes, 'Tag', 'DrawnLine'));
            delete(findall(app.ZPlaneAxes, 'Tag', 'DrawnLine'));

            %re-plot j-omega axis and its mapping
            plotImagAxis(app);
            
        end

        % Value changed function: FrequnciadeAmostragemSlider
        function FrequnciadeAmostragemSliderValueChanged(app, event)
            value = app.FrequnciadeAmostragemSlider.Value;
            app.Ta = 1/value;

            %clear plots
            delete(findall(app.SPlaneAxes, 'Tag', 'DrawnLine'));
            delete(findall(app.ZPlaneAxes, 'Tag', 'DrawnLine'));

            %re-plot j-omega axis and its mapping
            plotImagAxis(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MapeamentoplanosplanozPanel = uipanel(app.UIFigure);
            app.MapeamentoplanosplanozPanel.Title = 'Mapeamento plano s -> plano z';
            app.SPlaneAxes = uiaxes(app.MapeamentoplanosplanozPanel);
            title(app.SPlaneAxes, 'Title')
            xlabel(app.SPlaneAxes, 'X')
            ylabel(app.SPlaneAxes, 'Y')
            zlabel(app.SPlaneAxes, 'Z')
            app.ZPlaneAxes = uiaxes(app.MapeamentoplanosplanozPanel);
            title(app.ZPlaneAxes, 'Title')
            xlabel(app.ZPlaneAxes, 'X')
            ylabel(app.ZPlaneAxes, 'Y')
            zlabel(app.ZPlaneAxes, 'Z')
            app.MtodoListBoxLabel = uilabel(app.MapeamentoplanosplanozPanel);
            app.MtodoListBoxLabel.Text = 'Método';
            app.MtodoListBox = uilistbox(app.MapeamentoplanosplanozPanel);
            app.MtodoListBox.Items = {'Exato', 'Euler-Backward', 'Euler-Forward', 'Tustin'};
            app.MtodoListBox.ValueChangedFcn = createCallbackFcn(app, @MtodoListBoxValueChanged, true);
            app.MtodoListBox.Value = 'Exato';
            app.FrequnciadeAmostragemSliderLabel = uilabel(app.MapeamentoplanosplanozPanel);
            app.FrequnciadeAmostragemSliderLabel.Text = 'Frequência de Amostragem';
            app.FrequnciadeAmostragemSlider = uislider(app.MapeamentoplanosplanozPanel);
            app.FrequnciadeAmostragemSlider.Limits = [49 100];
            app.FrequnciadeAmostragemSlider.MajorTicks = [50 60 70 80 90 100];
            app.FrequnciadeAmostragemSlider.ValueChangedFcn = createCallbackFcn(app, @FrequnciadeAmostragemSliderValueChanged, true);
            app.FrequnciadeAmostragemSlider.MinorTicks = [55 65 75 85 95];
            app.FrequnciadeAmostragemSlider.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zplanedemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end